classdef EMGdecomp_VIZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        samplingRateEditField          matlab.ui.control.NumericEditField
        SamplingRateLabel              matlab.ui.control.Label
        motorUnitDropDown              matlab.ui.control.DropDown
        MotorUnitDropDownLabel         matlab.ui.control.Label
        LEditField                     matlab.ui.control.NumericEditField
        LLabel                         matlab.ui.control.Label
        rawDirectoryEditField          matlab.ui.control.EditField
        SpecifytheresultsmatfileLabel_2  matlab.ui.control.Label
        resultsDirectoryEditField      matlab.ui.control.EditField
        SpecifytheresultsmatfileLabel  matlab.ui.control.Label
        plotLamp                       matlab.ui.control.Lamp
        plotResultsButton              matlab.ui.control.Button
        rawDirectoryButton             matlab.ui.control.Button
        resultsDirectoryButton         matlab.ui.control.Button
        spinnerPanel                   matlab.ui.container.Panel
        spinnerHTML                    matlab.ui.control.HTML
        Panel                          matlab.ui.container.Panel
        firingRatePanel                matlab.ui.container.Panel
        firingRateAxes                 matlab.ui.control.UIAxes
        firingStrengthPanel            matlab.ui.container.Panel
        firingStrengthAxes             matlab.ui.control.UIAxes
        MUAPsPanel                     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Results
        Raw
        MUSelected
        Data
        MUAP_all
    end
    
    methods (Access = private)
        
        
        function toggleSpinner(app, enableFlag)
           
            app.spinnerHTML.Visible = enableFlag;
            
        end
        
        function toggleComponents(app, enableFlag)
            
            components = [app.rawDirectoryButton app.resultsDirectoryButton app.plotResultsButton ...
                app.LEditField app.rawDirectoryEditField app.resultsDirectoryEditField app.samplingRateEditField ...
                app.SamplingRateLabel app.SpecifytheresultsmatfileLabel app.MotorUnitDropDownLabel app.LLabel app.SpecifytheresultsmatfileLabel_2 ...
                app.motorUnitDropDown ...
                 app.Panel];
            set(components, 'Enable', enableFlag);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.Data.tiledLayout = tiledlayout(app.MUAPsPanel,1,1);
            app.spinnerHTML.Visible = "off";
            app.spinnerHTML.HTMLSource = "spinner.html";
            
            app.MUSelected = 1;
            app.MUAP_all = {};

        end

        % Button pushed function: resultsDirectoryButton
        function resultsDirectoryButtonPushed(app, event)

            [file, path] = uigetfile("*.mat", "Select the results.mat file to be visualized.");
            if ~isequal(file, 0)
                toggleComponents(app, "off")
                toggleSpinner(app, "on");
                drawnow;
                app.resultsDirectoryEditField.Value = fullfile(path,file);
                load(app.resultsDirectoryEditField.Value,"results");
                app.Results = results;
                app.motorUnitDropDown.ItemsData = 1:size(app.Results.B,2);
                app.motorUnitDropDown.Items  = "MU" + string(app.motorUnitDropDown.ItemsData);
            end
            toggleSpinner(app, "off");
            toggleComponents(app, "on");

        end

        % Button pushed function: rawDirectoryButton
        function rawDirectoryButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Select the raw data file that was deomposed with the algorithm.");
            if ~isequal(file, 0)
                app.rawDirectoryEditField.Value = fullfile(path,file);
                load(app.rawDirectoryEditField.Value,"SIG");
                app.Raw = flatten(SIG);
                app.MUAP_all = MUAP_cal(app.Results, app.Raw, app.LEditField.Value);
            end
        end

        % Button pushed function: plotResultsButton
        function plotResultsButtonPushed(app, event)
%             app.Data.tiledLayout = tiledlayout(app.MUAPsPanel,1,1);
            toggleComponents(app, "off")
            toggleSpinner (app, "on")
            drawnow;
            
            t = tiledlayout(app.MUAPsPanel,1,1);
            MUAP_plot(t, app.MUAP_all, app.MUSelected);

            cla(app.firingRateAxes);
            app.firingRateAxes = firing_rate_plot(app.firingRateAxes, app.Results, app.samplingRateEditField.Value, app.MUSelected);
            drawnow;
            
            cla(app.firingStrengthAxes);
            app.firingStrengthAxes = firing_strength_plot(app.firingStrengthAxes, app.Results, app.samplingRateEditField.Value, app.MUSelected);
            drawnow;
            
            toggleComponents(app, "on")
            toggleSpinner (app, "off")
            app.plotLamp.Enable = 'on';

        end

        % Value changed function: resultsDirectoryEditField
        function resultsDirectoryEditFieldValueChanged(app, event)
            value = app.resultsDirectoryEditField.Value;
            load(value,"results");
            app.Results = results;
            app.motorUnitDropDown.ItemsData = 1:size(app.Results.B,2);
            app.motorUnitDropDown.Items  = "MU" + string(app.motorUnitDropDown.ItemsData);
        end

        % Value changed function: rawDirectoryEditField
        function rawDirectoryEditFieldValueChanged(app, event)
            value = app.rawDirectoryEditField.Value;
            load(value,"SIG");
            app.Raw = flatten(SIG);
        end

        % Value changed function: motorUnitDropDown
        function motorUnitDropDownValueChanged(app, event)
            value = app.motorUnitDropDown.Value;
            app.MUSelected = value;
        end

        % Drop down opening function: motorUnitDropDown
        function motorUnitDropDownOpening(app, event)
            app.plotLamp.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.MUAPsPanel = uipanel(app.Panel);
            app.MUAPsPanel.Title = 'MUAPs';
            app.firingStrengthPanel = uipanel(app.Panel);
            app.firingStrengthPanel.Title = 'Firing Strength';
            app.firingStrengthAxes = uiaxes(app.firingStrengthPanel);
            xlabel(app.firingStrengthAxes, 'X')
            ylabel(app.firingStrengthAxes, 'Y')
            zlabel(app.firingStrengthAxes, 'Z')
            app.firingRatePanel = uipanel(app.Panel);
            app.firingRatePanel.Title = 'Firing Rate';
            app.firingRateAxes = uiaxes(app.firingRatePanel);
            xlabel(app.firingRateAxes, 'X')
            ylabel(app.firingRateAxes, 'Y')
            zlabel(app.firingRateAxes, 'Z')
            app.spinnerPanel = uipanel(app.UIFigure);
            app.spinnerHTML = uihtml(app.spinnerPanel);
            app.resultsDirectoryButton = uibutton(app.UIFigure, 'push');
            app.resultsDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @resultsDirectoryButtonPushed, true);
            app.resultsDirectoryButton.Icon = 'icons8-opened-folder-50.png';
            app.resultsDirectoryButton.Text = '';
            app.rawDirectoryButton = uibutton(app.UIFigure, 'push');
            app.rawDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @rawDirectoryButtonPushed, true);
            app.rawDirectoryButton.Icon = 'icons8-opened-folder-50.png';
            app.rawDirectoryButton.Text = '';
            app.plotResultsButton = uibutton(app.UIFigure, 'push');
            app.plotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @plotResultsButtonPushed, true);
            app.plotResultsButton.Text = 'Plot Results';
            app.plotLamp = uilamp(app.UIFigure);
            app.plotLamp.Enable = 'off';
            app.SpecifytheresultsmatfileLabel = uilabel(app.UIFigure);
            app.SpecifytheresultsmatfileLabel.Text = 'Specify the results.mat file location:';
            app.resultsDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.resultsDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @resultsDirectoryEditFieldValueChanged, true);
            app.SpecifytheresultsmatfileLabel_2 = uilabel(app.UIFigure);
            app.SpecifytheresultsmatfileLabel_2.Text = 'Specify the raw data ".mat" file location:';
            app.rawDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.rawDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @rawDirectoryEditFieldValueChanged, true);
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = 'L:';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.Value = 31;
            app.MotorUnitDropDownLabel = uilabel(app.UIFigure);
            app.MotorUnitDropDownLabel.Text = 'Motor Unit #:';
            app.motorUnitDropDown = uidropdown(app.UIFigure);
            app.motorUnitDropDown.DropDownOpeningFcn = createCallbackFcn(app, @motorUnitDropDownOpening, true);
            app.motorUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @motorUnitDropDownValueChanged, true);
            app.SamplingRateLabel = uilabel(app.UIFigure);
            app.SamplingRateLabel.Text = 'Sampling Rate:';
            app.samplingRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.samplingRateEditField.Value = 2048;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMGdecomp_VIZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end